classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        MaxymmEditFieldLabel           matlab.ui.control.Label
        MaxymmEditField                matlab.ui.control.NumericEditField
        MaxxmmEditFieldLabel           matlab.ui.control.Label
        MaxxmmEditField                matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        ExportPathButton               matlab.ui.control.Button
        FileEditFieldLabel             matlab.ui.control.Label
        FileEditField                  matlab.ui.control.EditField
        LoadButton                     matlab.ui.control.Button
        AddnewshrinkButton             matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        FastfeedspeedmsEditFieldLabel  matlab.ui.control.Label
        FastfeedspeedmsEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        fileName="" % Description
        nominalVelocity % Description
    end
    
    properties (Access = public)
        pathList % Description
    end
    
    methods (Access = public)
        function results = updatePlot(app)
            app.UIAxes.cla;
            for i=1:size(app.UITable.Data,1)
               line(app.UIAxes,[app.UITable.Data(i,1), app.UITable.Data(i,3)],[app.UITable.Data(i,2),app.UITable.Data(i,4)]) 
            end
        end
        
        function results = updateFileField(app)
            app.FileEditField.Value=app.fileName;
        end
        
        function results = getFileName(app)
            results = app.fileName;
        end
        
        function results = setFileName(app, fn)
            app.fileName=fn;
            updateFileField(app);
        end
        
        function results = resetAllData(app)
            app.UITable.Data=zeros(1,numel(app.UITable.ColumnName));
        end
        
        function results = sortPath(app)
            tempArray=[];
            for i=1:size(app.UITable.Data,1)
                tempArray(i)=0.5*(app.UITable.Data(i,1)+app.UITable.Data(i,3));
            end
            [sortedVal,index]=sort(tempArray);
            for i=1:size(app.UITable.Data,1)
                results(i,:)=app.UITable.Data(index(i),:);
            end
        end
        
        function results = getNominalVelocity(app)
            results=app.nominalVelocity;
        end
        
        function results = setNominalVelocity(app, nomvelocity)
            app.nominalVelocity=nomvelocity;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, loadfile)
            app.UITable.Data=zeros(1,numel(app.UITable.ColumnName));
            value = app.MaxxmmEditField.Value;
            app.UIAxes.XLim=[0 value];
            value = app.MaxymmEditField.Value;
            app.UIAxes.YLim=[0 value];
            app.setNominalVelocity(app.FastfeedspeedmsEditField.Value);
        end

        % Value changed function: MaxxmmEditField
        function MaxxmmEditFieldValueChanged(app, event)
            value = app.MaxxmmEditField.Value;
            app.UIAxes.XLim=[0 value];
        end

        % Value changed function: MaxymmEditField
        function MaxymmEditFieldValueChanged(app, event)
            value = app.MaxymmEditField.Value;
            app.UIAxes.YLim=[0 value];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            updatePlot(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path]=uigetfile('*.mat');
            drawnow;
            figure(app.UIFigure);
            if (file~=0)
                app.setFileName(fullfile(path,file));
                load(app.getFileName);
                for i=1:2:size(X,1)
                    if (i==1)
                        app.UITable.Data(1,1)=X(1);
                        app.UITable.Data(1,2)=Y(1);
                        app.UITable.Data(1,3)=X(2);
                        app.UITable.Data(1,4)=Y(2);
                        app.UITable.Data(1,5)=V(2);
                    else
                        app.UITable.Data=[app.UITable.Data; X(i) Y(i) X(i+1) Y(i+1) V(i+1)];
                    end
                end
                app.FastfeedspeedmsEditField.Value=V(1);
                updatePlot(app);
            end
        end

        % Button pushed function: ExportPathButton
        function ExportPathButtonPushed(app, event)
            exportData=sortPath(app);
            for i=1:size(exportData,1)
                if (i==1)
                    X=[exportData(1,1);exportData(1,3)];
                    Y=[exportData(1,2);exportData(1,4)];
                    V=[app.getNominalVelocity;exportData(1,5)];
                else
                    X=[X; exportData(i,1); exportData(i,3)];
                    Y=[Y; exportData(i,2); exportData(i,4)];
                    V=[V; app.getNominalVelocity;exportData(i,5)];
                end
            end
            [file,path]=uiputfile('*.mat');
            app.setFileName(fullfile(path,file));
            save(app.getFileName,'X','Y','V');
            drawnow;
            figure(app.UIFigure);
            app.setFileName(fullfile(path,file));
        end

        % Callback function
        function CreateNewButtonPushed(app, event)
            app.CreateNewButton.Enable='off';
            app.createFileDialog=createfile(app,app.fileName);
        end

        % Button pushed function: AddnewshrinkButton
        function AddnewshrinkButtonPushed(app, event)
            app.UITable.Data=[app.UITable.Data;0 0 0 0 0];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data=[0 0 0 0 0];
        end

        % Value changed function: FastfeedspeedmsEditField
        function FastfeedspeedmsEditFieldValueChanged(app, event)
            app.nominalVelocity = app.FastfeedspeedmsEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Heat shrink visualization map'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MaxymmEditFieldLabel = uilabel(app.UIFigure);
            app.MaxymmEditFieldLabel.Text = 'Max y (mm)';
            app.MaxymmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxymmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxymmEditFieldValueChanged, true);
            app.MaxymmEditField.Value = 800;
            app.MaxxmmEditFieldLabel = uilabel(app.UIFigure);
            app.MaxxmmEditFieldLabel.Text = 'Max x (mm)';
            app.MaxxmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxxmmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxxmmEditFieldValueChanged, true);
            app.MaxxmmEditField.Value = 1600;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Start point X'; 'Start point Y'; 'End point X'; 'End point Y'; 'Velocity'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ExportPathButton = uibutton(app.UIFigure, 'push');
            app.ExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPathButtonPushed, true);
            app.ExportPathButton.Text = 'Export Path';
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.UIFigure, 'text');
            app.FileEditField.Editable = 'off';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AddnewshrinkButton = uibutton(app.UIFigure, 'push');
            app.AddnewshrinkButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewshrinkButtonPushed, true);
            app.AddnewshrinkButton.Text = {'Add new shrink'; ''};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FastfeedspeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.FastfeedspeedmsEditFieldLabel.Text = 'Fast feed speed (m/s)';
            app.FastfeedspeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FastfeedspeedmsEditField.ValueChangedFcn = createCallbackFcn(app, @FastfeedspeedmsEditFieldValueChanged, true);
            app.FastfeedspeedmsEditField.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end